classdef orbitale_Aleksandra_Kot_FT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        OmnieMenu                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        OpisPanel                       matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        RysowanieorbitaliLabel          matlab.ui.control.Label
        ZapisPanel                      matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        FigurezorbitalempowinienbyotwartypodczaszapisuLabel  matlab.ui.control.Label
        ZapiszobrazekorbitalButton      matlab.ui.control.Button
        WybierzfunkcjdonarysowaniaPanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        NaleyustawiwczeniejodpowiednieliczbykwantoweLabel  matlab.ui.control.Label
        RysujButton                     matlab.ui.control.Button
        FunkcjeDropDown                 matlab.ui.control.DropDown
        FunkcjeDropDownLabel            matlab.ui.control.Label
        UstawwasnociwykresuorbitaliPanel  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        kolortaDropDown                 matlab.ui.control.DropDown
        kolortaDropDownLabel            matlab.ui.control.Label
        mapawykresuDropDown             matlab.ui.control.DropDown
        mapawykresuDropDownLabel        matlab.ui.control.Label
        ZatwierdrysujorbitalButton      matlab.ui.control.Button
        WykresyfunkcjiPanel             matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        mapawykresudlaharmonikDropDown  matlab.ui.control.DropDown
        mapawykresudlaharmonikponowniekliknijrysujLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        WpiszliczbykwantowePanel        matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        llLabel                         matlab.ui.control.Label
        n1Label                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        m_EditField                     matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        l_EditField                     matlab.ui.control.NumericEditField
        lEditFieldLabel                 matlab.ui.control.Label
        n_EditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        n=1 % liczba kwantowa n
        l=0 % liczba kwantowa l
        m=0 % liczba kwantowa m
        map ='parula' % mapa kolorow dla orbitali
        colorrr='w' % tlo wykresu dla orbitali
        f % figure orbital
        map2 ='parula' % mapa kolorow dla harmoniki
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OmnieMenu
        function OmnieMenuSelected(app, event)
            omnie = sprintf(['Autor aplikacji: Aleksandra Kot \n' ...
                'Adres email: alkot@student.agh.edu.pl']);
            uialert(app.UIFigure,omnie, 'O mnie', 'Icon', 'Info');
        end

        % Value changed function: n_EditField
        function n_EditFieldValueChanged(app, event)
            app.n = app.n_EditField.Value;
        end

        % Value changed function: l_EditField
        function l_EditFieldValueChanged(app, event)
            app.l = app.l_EditField.Value;
        end

        % Value changed function: m_EditField
        function m_EditFieldValueChanged(app, event)
            app.m = app.m_EditField.Value;
            
        end

        % Button pushed function: ZatwierdrysujorbitalButton
        function ZatwierdrysujorbitalButtonPushed(app, event)
            if app.l>=app.n || abs(app.m)>=app.n
                warndlg('Liczba kwantowa orbitalna l nie może być większa lub równa liczbie kwantowej głównej n, a liczba kwantowa magnetyczna m musi mieścić się w granicach [-l, l]!');
                app.l=app.n-1;
                app.l_EditField.Value=app.l;
                if (abs(app.m)>abs(app.l) || abs(app.m)>=app.n)
                if app.m>app.l
                    app.m=app.l;
                app.m_EditField.Value=app.m;
                else
                    app.m=-abs(app.l);
                    app.m_EditField.Value=app.m;
                end
                end
            end

            
            grid = linspace(-100, 100, 250);
            [x, y, z] = meshgrid(grid, grid, grid);
            r = sqrt(x.^2 + y.^2 + z.^2);
            theta = acos(z ./ r);
            phi = atan2(y, x);

            orbitalll = RadialHarm(app.n, app.l, r).*SphericalHarmonicReal(app.l, app.m, theta, phi);
            app.f=figure;
            isosurface(orbitalll.^2,1e-5,sign(orbitalll));
            view(3);
            app.f.Color=app.colorrr; %jezeli wybierzemy mape wykresu przed rysowaniem orbitalu, to wyskoczy figure bez niczego
            colormap(app.map);
            axis equal;
        end

        % Value changed function: mapawykresuDropDown
        function mapawykresuDropDownValueChanged(app, event)
            app.map = app.mapawykresuDropDown.Value;
        end

        % Drop down opening function: mapawykresuDropDown
        function mapawykresuDropDownOpening(app, event)
            app.mapawykresuDropDown.Items = {'parula','winter','autumn','summer','spring','gray','pink','abyss','cool','hot','sky'};
        end

        % Drop down opening function: kolortaDropDown
        function kolortaDropDownOpening(app, event)
            app.kolortaDropDown.Items = {'fioletowy' ,'cyjanowy', 'zolty'};
        end

        % Value changed function: kolortaDropDown
        function kolortaDropDownValueChanged(app, event)
            color = app.kolortaDropDown.Value;
            if strcmp(color,'fioletowy')
                str='#E5D4E8';
                app.colorrr=sscanf(str(2:end),'%2x%2x%2x',[1 3])/255;
            elseif strcmp(color,'cyjanowy')
                str='#CAECEC';
                app.colorrr=sscanf(str(2:end),'%2x%2x%2x',[1 3])/255;
            elseif strcmp(color,'zolty')
                str='#EDF7B9';
                app.colorrr=sscanf(str(2:end),'%2x%2x%2x',[1 3])/255;
            end
               
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Drop down opening function: FunkcjeDropDown
        function FunkcjeDropDownOpening(app, event)
            app.FunkcjeDropDown.Items = {'Harmonika sferyczna', 'Funkcja radialna', 'Funkcja radialna * r', 'Funkcja radialna^2*r^2', 'Stowarzyszone Wielomiany Laguerra', 'Stowarzyszone Wielomiany Lagrangea'};
        end

        % Value changed function: FunkcjeDropDown
        function FunkcjeDropDownValueChanged(app, event)

        end

        % Button pushed function: RysujButton
        function RysujButtonPushed(app, event)
            fun = app.FunkcjeDropDown.Value;
            if app.l>=app.n || abs(app.m)>=app.n %%sprawdzanie, czy liczby kwantowe sa poprawnie wpisane
                warndlg('Liczba kwantowa orbitalna l nie może być większa lub równa liczbie kwantowej głównej n, a liczba kwantowa magnetyczna m musi mieścić się w granicach [-l, l]!');
                app.l=app.n-1;
                app.l_EditField.Value=app.l;
                if (abs(app.m)>abs(app.l) || abs(app.m)>=app.n)
                if app.m>app.l
                    app.m=app.l;
                app.m_EditField.Value=app.m;
                else
                    app.m=-abs(app.l);
                    app.m_EditField.Value=app.m;
                end
                end
            end
            axes(app.UIAxes);
            if strcmp(fun, 'Harmonika sferyczna') %%rysowanie poszczegolnych wykresow na UIAxes
                dx = pi/60;
                col = 0:dx:pi;
                az = 0:dx:2*pi;
                [phi,theta] = meshgrid(az,col);
                Ylm2=SphericalHarmonic(app.l, app.m,theta, phi);
                [Xm,Ym,Zm] = sph2cart(phi, pi/2-theta, abs(real(Ylm2)));
                surf(app.UIAxes, Xm, Ym, Zm,'EdgeColor','none','FaceColor','interp','FaceAlpha',0.5);
                colormap(app.UIAxes, app.map2);
                set(app.UIAxes,'DataAspectRatio',[10 10 10])
                xlabel(app.UIAxes, 'X-axis');
                ylabel(app.UIAxes, 'Y-axis');
                zlabel(app.UIAxes, 'Z-axis');
                title(app.UIAxes, 'Harmonika Sferyczna');
            elseif strcmp(fun, 'Funkcja radialna')
                r=linspace(0,40,100);
                gfgd=RadialHarm (app.n,app.l,r);
                plot(app.UIAxes, r, gfgd, 'Color', '#8F44AD');
                view(app.UIAxes,2);
                ax=(app.UIAxes);
                ax.DataAspectRatioMode = 'auto';
                title(app.UIAxes, 'Funkcja radialna');
            elseif strcmp(fun, 'Stowarzyszone Wielomiany Laguerra')
                    a=linspace(-100,100,100);
                    gx=AssociatedLaguerrePol(app.n-app.l-1,2*app.l+1);
                    plot(app.UIAxes, a, gx(a),'Color', '#8F44AD');
                    view(app.UIAxes, 2);
                    ax=(app.UIAxes);
                    ax.DataAspectRatioMode = 'auto';
                    title(app.UIAxes, 'Stowarzyszone Wielomiany Laguerra');
            elseif strcmp(fun, 'Stowarzyszone Wielomiany Lagrangea')
                a=linspace(-0.9,0.9,50);
                Plm = legendre(app.l,a);
                    if app.l ~= 0
                        Plm = reshape(Plm(abs(app.m)+1, :, :), size(a));
                    end
                plot(app.UIAxes, a, Plm,'Color', '#8F44AD');
                view(app.UIAxes, 2);
                ax=(app.UIAxes);
                ax.DataAspectRatioMode = 'auto';
                title(app.UIAxes, 'Stowarzyszone Wielomiany Lagrangea');
            elseif strcmp(fun, 'Funkcja radialna * r')
                r=linspace(0,40,200);
                gfgd=RadialHarm (app.n,app.l,r);
                rad_r=gfgd.*r;
                plot(app.UIAxes, r, rad_r,'Color', '#8F44AD');
                view(app.UIAxes,2);
                ax=(app.UIAxes);
                ax.DataAspectRatioMode = 'auto';
                title(app.UIAxes, 'Funkcja radialna*r');
            elseif strcmp(fun, 'Funkcja radialna^2*r^2')
                r=linspace(0,40,200);
                gfgd=RadialHarm (app.n,app.l,r);
                rad_r2=gfgd.^2.*r.^2;
                plot(app.UIAxes, r, rad_r2,'Color', '#8F44AD');
                view(app.UIAxes,2);
                ax=(app.UIAxes);
                ax.DataAspectRatioMode = 'auto';
                title(app.UIAxes, 'Funkcja radialna*r');
            end

        end

        % Button pushed function: ZapiszobrazekorbitalButton
        function ZapiszobrazekorbitalButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                saveas(app.f,[filepath filename]);
            end
        end

        % Drop down opening function: mapawykresudlaharmonikDropDown
        function mapawykresudlaharmonikDropDownOpening(app, event)
            app.mapawykresudlaharmonikDropDown.Items = {'parula','winter','autumn','summer','spring','gray','pink','abyss','cool','hot','sky'};
        end

        % Value changed function: mapawykresudlaharmonikDropDown
        function mapawykresudlaharmonikDropDownValueChanged(app, event)
            app.map2 = app.mapawykresudlaharmonikDropDown.Value;
            RysujButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OmnieMenu = uimenu(app.Menu);
            app.OmnieMenu.MenuSelectedFcn = createCallbackFcn(app, @OmnieMenuSelected, true);
            app.OmnieMenu.Text = 'O mnie';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.WpiszliczbykwantowePanel = uipanel(app.GridLayout);
            app.WpiszliczbykwantowePanel.Title = 'Wpisz liczby kwantowe:';
            app.GridLayout5 = uigridlayout(app.WpiszliczbykwantowePanel);
            app.nEditFieldLabel = uilabel(app.GridLayout5);
            app.nEditFieldLabel.Text = 'n';
            app.n_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.n_EditField.Limits = [0 5];
            app.n_EditField.RoundFractionalValues = 'on';
            app.n_EditField.ValueChangedFcn = createCallbackFcn(app, @n_EditFieldValueChanged, true);
            app.n_EditField.Value = 1;
            app.lEditFieldLabel = uilabel(app.GridLayout5);
            app.lEditFieldLabel.Text = 'l';
            app.l_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.l_EditField.Limits = [0 4];
            app.l_EditField.ValueChangedFcn = createCallbackFcn(app, @l_EditFieldValueChanged, true);
            app.mEditFieldLabel = uilabel(app.GridLayout5);
            app.mEditFieldLabel.Text = 'm';
            app.m_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.m_EditField.Limits = [-4 4];
            app.m_EditField.ValueChangedFcn = createCallbackFcn(app, @m_EditFieldValueChanged, true);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '0-5';
            app.n1Label = uilabel(app.GridLayout5);
            app.n1Label.Text = '0-(n-1)';
            app.llLabel = uilabel(app.GridLayout5);
            app.llLabel.Text = '(-l) - l';
            app.WykresyfunkcjiPanel = uipanel(app.GridLayout);
            app.WykresyfunkcjiPanel.Title = 'Wykresy funkcji:';
            app.GridLayout8 = uigridlayout(app.WykresyfunkcjiPanel);
            app.UIAxes = uiaxes(app.GridLayout8);
            title(app.UIAxes, 'Funkcje')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.mapawykresudlaharmonikponowniekliknijrysujLabel = uilabel(app.GridLayout8);
            app.mapawykresudlaharmonikponowniekliknijrysujLabel.Text = 'mapa wykresu (dla harmonik)';
            app.mapawykresudlaharmonikDropDown = uidropdown(app.GridLayout8);
            app.mapawykresudlaharmonikDropDown.Items = {'Wybierz'};
            app.mapawykresudlaharmonikDropDown.DropDownOpeningFcn = createCallbackFcn(app, @mapawykresudlaharmonikDropDownOpening, true);
            app.mapawykresudlaharmonikDropDown.ValueChangedFcn = createCallbackFcn(app, @mapawykresudlaharmonikDropDownValueChanged, true);
            app.mapawykresudlaharmonikDropDown.Value = 'Wybierz';
            app.UstawwasnociwykresuorbitaliPanel = uipanel(app.GridLayout);
            app.UstawwasnociwykresuorbitaliPanel.Title = 'Ustaw własności wykresu orbitali:';
            app.GridLayout3 = uigridlayout(app.UstawwasnociwykresuorbitaliPanel);
            app.ZatwierdrysujorbitalButton = uibutton(app.GridLayout3, 'push');
            app.ZatwierdrysujorbitalButton.ButtonPushedFcn = createCallbackFcn(app, @ZatwierdrysujorbitalButtonPushed, true);
            app.ZatwierdrysujorbitalButton.Text = 'Zatwierdź - rysuj orbital';
            app.mapawykresuDropDownLabel = uilabel(app.GridLayout3);
            app.mapawykresuDropDownLabel.Text = 'mapa wykresu';
            app.mapawykresuDropDown = uidropdown(app.GridLayout3);
            app.mapawykresuDropDown.Items = {'Wybierz'};
            app.mapawykresuDropDown.DropDownOpeningFcn = createCallbackFcn(app, @mapawykresuDropDownOpening, true);
            app.mapawykresuDropDown.ValueChangedFcn = createCallbackFcn(app, @mapawykresuDropDownValueChanged, true);
            app.mapawykresuDropDown.Placeholder = ',';
            app.mapawykresuDropDown.Value = 'Wybierz';
            app.kolortaDropDownLabel = uilabel(app.GridLayout3);
            app.kolortaDropDownLabel.Text = 'kolor tła';
            app.kolortaDropDown = uidropdown(app.GridLayout3);
            app.kolortaDropDown.Items = {'Wybierz'};
            app.kolortaDropDown.DropDownOpeningFcn = createCallbackFcn(app, @kolortaDropDownOpening, true);
            app.kolortaDropDown.ValueChangedFcn = createCallbackFcn(app, @kolortaDropDownValueChanged, true);
            app.kolortaDropDown.Value = 'Wybierz';
            app.WybierzfunkcjdonarysowaniaPanel = uipanel(app.GridLayout);
            app.WybierzfunkcjdonarysowaniaPanel.Title = 'Wybierz funkcję do narysowania:';
            app.GridLayout4 = uigridlayout(app.WybierzfunkcjdonarysowaniaPanel);
            app.FunkcjeDropDownLabel = uilabel(app.GridLayout4);
            app.FunkcjeDropDownLabel.Text = 'Funkcje:';
            app.FunkcjeDropDown = uidropdown(app.GridLayout4);
            app.FunkcjeDropDown.Items = {'Wybierz'};
            app.FunkcjeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FunkcjeDropDownOpening, true);
            app.FunkcjeDropDown.ValueChangedFcn = createCallbackFcn(app, @FunkcjeDropDownValueChanged, true);
            app.FunkcjeDropDown.Value = 'Wybierz';
            app.RysujButton = uibutton(app.GridLayout4, 'push');
            app.RysujButton.ButtonPushedFcn = createCallbackFcn(app, @RysujButtonPushed, true);
            app.RysujButton.Text = 'Rysuj';
            app.NaleyustawiwczeniejodpowiednieliczbykwantoweLabel = uilabel(app.GridLayout4);
            app.NaleyustawiwczeniejodpowiednieliczbykwantoweLabel.Text = {'Należy ustawić wcześniej'; 'odpowiednie liczby kwantowe'};
            app.ZapisPanel = uipanel(app.GridLayout);
            app.ZapisPanel.Title = 'Zapis:';
            app.GridLayout6 = uigridlayout(app.ZapisPanel);
            app.ZapiszobrazekorbitalButton = uibutton(app.GridLayout6, 'push');
            app.ZapiszobrazekorbitalButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszobrazekorbitalButtonPushed, true);
            app.ZapiszobrazekorbitalButton.Text = 'Zapisz obrazek - orbital';
            app.FigurezorbitalempowinienbyotwartypodczaszapisuLabel = uilabel(app.GridLayout6);
            app.FigurezorbitalempowinienbyotwartypodczaszapisuLabel.Text = {'Figure z orbitalem powinien'; 'być otwarty podczas zapisu'};
            app.OpisPanel = uipanel(app.GridLayout);
            app.OpisPanel.Title = 'Opis';
            app.GridLayout7 = uigridlayout(app.OpisPanel);
            app.RysowanieorbitaliLabel = uilabel(app.GridLayout7);
            app.RysowanieorbitaliLabel.Text = {'Rysowanie orbitali dla zadanych'; 'liczb kwantowych n, l oraz m wraz'; 'z odpowiadającymi im używanymi'; 'funkcjami'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orbitale_Aleksandra_Kot_FT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end